classdef appFinal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        Tab1                    matlab.ui.container.Tab
        UIAxes                  matlab.ui.control.UIAxes
        PlotButton              matlab.ui.control.Button
        ListenButton            matlab.ui.control.Button
        Tab2                    matlab.ui.container.Tab
        UIAxes_2                matlab.ui.control.UIAxes
        PlotButton_2            matlab.ui.control.Button
        ListenButton_2          matlab.ui.control.Button
        Tab3                    matlab.ui.container.Tab
        UIAxes_3                matlab.ui.control.UIAxes
        PlotButton_3            matlab.ui.control.Button
        ListenButton_3          matlab.ui.control.Button
        Tab4                    matlab.ui.container.Tab
        UIAxes_4                matlab.ui.control.UIAxes
        PlotButton_4            matlab.ui.control.Button
        Tab5                    matlab.ui.container.Tab
        UIAxes_5                matlab.ui.control.UIAxes
        PlotButton_5            matlab.ui.control.Button
        Tab6                    matlab.ui.container.Tab
        UIAxes_6                matlab.ui.control.UIAxes
        PlotButton_6            matlab.ui.control.Button
        Tab7                    matlab.ui.container.Tab
        UIAxes_7                matlab.ui.control.UIAxes
        PlotButton_7            matlab.ui.control.Button
        MeanZCREditFieldLabel   matlab.ui.control.Label
        MeanZCREditField        matlab.ui.control.NumericEditField
        Tab8                    matlab.ui.container.Tab
        UIAxes_8                matlab.ui.control.UIAxes
        PlotButton_8            matlab.ui.control.Button
        Tab9                    matlab.ui.container.Tab
        UIAxes_9                matlab.ui.control.UIAxes
        PlotButton_9            matlab.ui.control.Button
        Tab10                   matlab.ui.container.Tab
        UIAxes_10               matlab.ui.control.UIAxes
        PlotButton_10           matlab.ui.control.Button
        Panel                   matlab.ui.container.Panel
        MaleLabel               matlab.ui.control.Label
        MaleLamp                matlab.ui.control.Lamp
        FemaleLabel             matlab.ui.control.Label
        FemaleLamp              matlab.ui.control.Lamp
        PitchEditFieldLabel     matlab.ui.control.Label
        PitchEditField          matlab.ui.control.NumericEditField
        GenderDetectionButton   matlab.ui.control.Button
        NotavoiceLampLabel      matlab.ui.control.Label
        NotavoiceLamp           matlab.ui.control.Lamp
        HNRButton               matlab.ui.control.Button
        EditField_2             matlab.ui.control.NumericEditField
        ResetButton             matlab.ui.control.Button
        TabGroup2               matlab.ui.container.TabGroup
        RecordTab               matlab.ui.container.Tab
        StartRecordingButton    matlab.ui.control.Button
        TimesGaugeLabel         matlab.ui.control.Label
        TimesGauge              matlab.ui.control.SemicircularGauge
        EditField               matlab.ui.control.NumericEditField
        SaveButton              matlab.ui.control.Button
        UploadTab               matlab.ui.container.Tab
        UploadButton            matlab.ui.control.Button
        FileNameEditFieldLabel  matlab.ui.control.Label
        FileNameEditField       matlab.ui.control.EditField
        UploadedLampLabel       matlab.ui.control.Label
        UploadedLamp            matlab.ui.control.Lamp
    end

    
    properties (Access = private)
        y = [];
        y1 = [];
        y2 = [];
        Fs = 0;
        F0 = 0;
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartRecordingButton
        function StartRecordingButtonPushed(app, event)
            c = clock;
            m = 0;
            s_tot = 0;
            app.Fs = 44100;
            recObj = audiorecorder(app.Fs,16,1);
            record(recObj,5);
            
            while s_tot < 5
                c1 = clock;
                if c1(5) ~= c(5)
                    s_tot = c1(6)-c(6)+60;
                    m = m+1;
                    c(5) = c1(5);
                else
                    s_tot = c1(6)-c(6)+60*m;  
                end
                app.EditField.Value = s_tot;
                app.TimesGauge.Value = s_tot;
                pause(0.001)
            end
  
            app.y=getaudiodata(recObj);
          
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.UIAxes.XLim = [0 5*app.Fs]; 
            plot(app.UIAxes,app.y)
        end

        % Button pushed function: PlotButton_2
        function PlotButton_2Pushed(app, event)
            maxval=max(app.y(1:end,1));
            data = app.y(1:end,1)./maxval;
            
            % do framing
            f_d = 0.025;
            frame_size = round(f_d * app.Fs);
            n_f = floor(length(data)/frame_size); %no. of frames
            temp = 0;
            frames = zeros(n_f,1103);
            for i = 1 : n_f
                frames(i,:) = data(temp + 1 : temp + frame_size);
                temp = temp + frame_size;
            end
            
            % silence removal based on max amplitude
            m_amp = abs(max(frames,[],2)); % find maximum of each frame
            id = m_amp > 0.08; % finding ID of frames with max amp > 0.03
            fr_ws = frames(id,:); % frames without silence
            
            % reconstruct signal
            app.y1 = reshape(fr_ws',1,[]);
            app.y1=app.y1.*maxval;
            
            app.UIAxes_2.XLim = [0 5*app.Fs];
            plot(app.UIAxes_2,app.y1);
        end

        % Button pushed function: ListenButton
        function ListenButtonPushed(app, event)
            sound(app.y,app.Fs)
        end

        % Button pushed function: ListenButton_2
        function ListenButton_2Pushed(app, event)
            sound(app.y1,app.Fs)
        end

        % Button pushed function: PlotButton_3
        function PlotButton_3Pushed(app, event)
            [b, a] = butter(10, 1000/(app.Fs/2), 'low');
            app.y2 = 5*filter(b,a,app.y);
            

            plot(app.UIAxes_3,app.y2)
            
        end

        % Button pushed function: ListenButton_3
        function ListenButton_3Pushed(app, event)
            sound(app.y2,app.Fs)
        end

        % Button pushed function: PlotButton_4
        function PlotButton_4Pushed(app, event)
            h = spectrum.welch; % create welch spectrum object
            d = psd(h, app.y,'Fs', app.Fs);
            d1= psd(h, app.y2,'Fs', app.Fs);
            
            
            plot(app.UIAxes_4,10^(-3)*d.Frequencies,10*log10(d.data),'r',10^(-3)*d.Frequencies,10*log10(d1.data),'g')
            legend(app.UIAxes_4,'Before Filtering','After Filtering');
        end

        % Button pushed function: GenderDetectionButton
        function GenderDetectionButtonPushed(app, event)
            % Set the frame rate
            frame = 3500;
            
            % Design a Butterworth filter using the built-in butter function
            [b0, a0] = butter(5, 320/(app.Fs/2), 'low');
            
            % Initialize an array to store the fundamental frequency of each frame
            app.F0 = zeros(1, floor(length(app.y2)/frame));
            
            % Process each frame of the audio file
            for i = 1:floor(length(app.y2)/frame)
                % Extract the i-th frame from the audio data
                x = app.y2(1+(i-1)*frame:i*frame);
                
                % Filter the absolute value of the audio data using the built-in filter function
                xin = filter(b0, a0, abs(x));
                
                % Normalize the filtered audio data
                xin = xin - mean(xin);
                
                % Create a shifted version of the normalized audio data
                x2 = [xin(2:end); 0];
                
                % Count the number of zero-crossings in the normalized audio data
                zc = sum((xin > 0 & x2 < 0) | (xin < 0 & x2 > 0));
                
                % Calculate the fundamental frequency of the i-th frame
                app.F0(i) = 0.5 * app.Fs * zc / length(x);
            end
            
            % Calculate the mean of the fundamental frequencies of all frames
            Fx = mean(app.F0);
            
            % Print the estimated mean fundamental frequency
            app.PitchEditField.Value = Fx;
            
            % Check if the audio contains voice
            if Fx < 85
                app.NotavoiceLamp.Color = 'red';
            else
                % Classify the speaker's gender based on the mean frequency
                if Fx > 165
                    app.FemaleLamp.Color = 'green';
                    
                else
                    app.MaleLamp.Color = 'green';
                    
                end
            end
        end

        % Button pushed function: PlotButton_5
        function PlotButton_5Pushed2(app, event)
            xf = abs(fft(app.y, 2^nextpow2(length(app.y))));
            Hpsd = dspdata.psd(xf(1:length(xf)/2),'Fs',app.Fs);
            plot(app.UIAxes_5, 10^(-3)*Hpsd.Frequencies, 10*log10(Hpsd.data));
        end

        % Button pushed function: PlotButton_6
        function PlotButton_6Pushed2(app, event)
            xf2 = abs(fft(app.y2,2^nextpow2(length(app.y2))));
            Hpsd2= dspdata.psd(xf2(1:length(xf2)/2),'Fs',app.Fs);
            plot(app.UIAxes_6, 10^(-3)*Hpsd2.Frequencies, 10*log10(Hpsd2.data));
        end

        % Button pushed function: HNRButton
        function HNRButtonPushed(app, event)
            segment_length = 500;
            num_segments = floor(length(app.y) / segment_length);
            HNR = zeros(1, num_segments);
            
            for i = 1:num_segments
                segment = app.y((i-1)*segment_length+1 : i*segment_length);
                [autocorr, lags] = xcorr(segment, 'coeff');
                [~,I] = max(autocorr(lags>0));
                lag = lags(I);
                T0 = lag / app.Fs;
                t = (0:length(segment)-1) / app.Fs;
                harmonic = cos(2*pi*t/T0);
                E_harmonic = sum((segment .* harmonic).^2);
                E_noise = sum((segment .* (1-harmonic)).^2);
                HNR(i) = 10 * log10(E_harmonic / E_noise);
            end
            
            app.EditField_2.Value = mean(HNR);
        end

        % Button pushed function: PlotButton_7
        function PlotButton_7Pushed(app, event)
            f_d = 0.025;
            frame_size = round(f_d * app.Fs);
            n_f = floor(length(app.y)/frame_size); %no. of frames
            temp = 0;
            frames = zeros(n_f,1103);
            for i = 1 : n_f
                frames(i,:) = app.y(temp + 1 : temp + frame_size);
                temp = temp + frame_size;
            end
            zcr=zeros(1,size(frames,1));
            for i= 1:size(frames,1)
                for j=1:size(frames,2)-1
                    zcr(i)=zcr(i)+abs(sign(frames(i,j+1))-sign(frames(i,j)));
                end
                zcr(i)=zcr(i)/(2*length(frames));
            end
           
            plot(app.UIAxes_7,zcr)
            app.MeanZCREditField.Value = mean(zcr);
        end

        % Button pushed function: PlotButton_8
        function PlotButton_8Pushed(app, event)
            r1 = spectralRolloffPoint(app.y,app.Fs);
            t1 = linspace(0,size(app.y,1)/app.Fs,size(r1,1));
            
            plot(app.UIAxes_8,t1,r1)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.MaleLamp.Color = [0.94,0.94,0.94];
            app.FemaleLamp.Color = [0.94,0.94,0.94];
            app.NotavoiceLamp.Color = [0.94,0.94,0.94];
            app.EditField_2.Value = 0;
            app.PitchEditField.Value = 0;
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            [file,p] = uigetfile('*.wav','Select the .wav file only');
            if file(length(file)-3:length(file))=='.wav'
                
                app.UploadedLamp.Color='green';
                
                s = strcat(p,file);
                app.FileNameEditField.Value = file;
                [app.y ,app.Fs]=audioread(s);
                
            end  
            
        end

        % Button pushed function: PlotButton_9
        function PlotButton_9Pushed(app, event)
            plot(app.UIAxes_9,app.F0)
        end

        % Button pushed function: PlotButton_10
        function PlotButton_10Pushed(app, event)
            l = length(app.y2); 
            NFFT = 2^nextpow2(l); 
            f = app.Fs/2*linspace(0,1,NFFT/2+1); 
            xf = abs(fft(app.y, NFFT));
            xf2 = abs(fft(app.y2, NFFT));
            plot(app.UIAxes_10, 10^(-3)*f, xf(1:NFFT/2+1),'g', 10^(-3)*f, xf2(1:NFFT/2+1), 'r')
            legend(app.UIAxes_10,'Before Filtering','After Filtering');
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            audiowrite(uiputfile('*.wav'),app.y,app.Fs)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab1 = uitab(app.TabGroup);
            app.Tab1.Title = 'Tab1';
            app.UIAxes = uiaxes(app.Tab1);
            title(app.UIAxes, 'Voice Signal')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            app.UIAxes.XTick = [0 44100 88200 132300 176400 220500];
            app.UIAxes.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'};
            app.PlotButton = uibutton(app.Tab1, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ListenButton = uibutton(app.Tab1, 'push');
            app.ListenButton.ButtonPushedFcn = createCallbackFcn(app, @ListenButtonPushed, true);
            app.ListenButton.Text = 'Listen';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.UIAxes_2 = uiaxes(app.Tab2);
            title(app.UIAxes_2, 'Silence removed Voice Signal')
            xlabel(app.UIAxes_2, 'Time')
            ylabel(app.UIAxes_2, 'Amplitude')
            app.UIAxes_2.XTick = [0 44100 88200 132300 176400 220500];
            app.UIAxes_2.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'};
            app.PlotButton_2 = uibutton(app.Tab2, 'push');
            app.PlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_2Pushed, true);
            app.PlotButton_2.Text = 'Plot';
            app.ListenButton_2 = uibutton(app.Tab2, 'push');
            app.ListenButton_2.ButtonPushedFcn = createCallbackFcn(app, @ListenButton_2Pushed, true);
            app.ListenButton_2.Text = 'Listen';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.UIAxes_3 = uiaxes(app.Tab3);
            title(app.UIAxes_3, 'Noise Removed Voice Signal')
            xlabel(app.UIAxes_3, 'Time')
            ylabel(app.UIAxes_3, 'Amplitude')
            app.UIAxes_3.XTick = [0 44100 88200 132300 176400 220500];
            app.UIAxes_3.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'};
            app.PlotButton_3 = uibutton(app.Tab3, 'push');
            app.PlotButton_3.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_3Pushed, true);
            app.PlotButton_3.Text = 'Plot';
            app.ListenButton_3 = uibutton(app.Tab3, 'push');
            app.ListenButton_3.ButtonPushedFcn = createCallbackFcn(app, @ListenButton_3Pushed, true);
            app.ListenButton_3.Text = 'Listen';
            app.Tab4 = uitab(app.TabGroup);
            app.Tab4.Title = 'Tab4';
            app.UIAxes_4 = uiaxes(app.Tab4);
            title(app.UIAxes_4, 'Welch Power Spectral Density Estimate')
            xlabel(app.UIAxes_4, 'Fequency(kHz)')
            ylabel(app.UIAxes_4, 'Power/Frequency(dB/Hz)')
            app.PlotButton_4 = uibutton(app.Tab4, 'push');
            app.PlotButton_4.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_4Pushed, true);
            app.PlotButton_4.Text = 'Plot';
            app.Tab5 = uitab(app.TabGroup);
            app.Tab5.Title = 'Tab5';
            app.UIAxes_5 = uiaxes(app.Tab5);
            title(app.UIAxes_5, 'Power Spectral Density')
            xlabel(app.UIAxes_5, 'Fequency(kHz)')
            ylabel(app.UIAxes_5, 'Power/Frequency(dB/Hz)')
            app.PlotButton_5 = uibutton(app.Tab5, 'push');
            app.PlotButton_5.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_5Pushed2, true);
            app.PlotButton_5.Text = 'Plot';
            app.Tab6 = uitab(app.TabGroup);
            app.Tab6.Title = 'Tab6';
            app.UIAxes_6 = uiaxes(app.Tab6);
            title(app.UIAxes_6, 'Power Spectral Density(After removing noise)')
            xlabel(app.UIAxes_6, 'Fequency(kHz)')
            ylabel(app.UIAxes_6, 'Power/Frequency(dB/Hz)')
            app.PlotButton_6 = uibutton(app.Tab6, 'push');
            app.PlotButton_6.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_6Pushed2, true);
            app.PlotButton_6.Text = 'Plot';
            app.Tab7 = uitab(app.TabGroup);
            app.Tab7.Title = 'Tab7';
            app.UIAxes_7 = uiaxes(app.Tab7);
            title(app.UIAxes_7, 'Zero Crossing Rate')
            xlabel(app.UIAxes_7, 'Samples')
            ylabel(app.UIAxes_7, 'Zero Crossing Rate')
            app.PlotButton_7 = uibutton(app.Tab7, 'push');
            app.PlotButton_7.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_7Pushed, true);
            app.PlotButton_7.Text = 'Plot';
            app.MeanZCREditFieldLabel = uilabel(app.Tab7);
            app.MeanZCREditFieldLabel.Text = 'Mean ZCR';
            app.MeanZCREditField = uieditfield(app.Tab7, 'numeric');
            app.Tab8 = uitab(app.TabGroup);
            app.Tab8.Title = 'Tab8';
            app.UIAxes_8 = uiaxes(app.Tab8);
            title(app.UIAxes_8, 'Spectral Roll-off Rate')
            xlabel(app.UIAxes_8, 'Time(s)')
            ylabel(app.UIAxes_8, 'Roll-off point(Hz)')
            app.PlotButton_8 = uibutton(app.Tab8, 'push');
            app.PlotButton_8.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_8Pushed, true);
            app.PlotButton_8.Text = 'Plot';
            app.Tab9 = uitab(app.TabGroup);
            app.Tab9.Title = 'Tab9';
            app.UIAxes_9 = uiaxes(app.Tab9);
            title(app.UIAxes_9, 'Frequency Range')
            xlabel(app.UIAxes_9, 'Frame No.')
            ylabel(app.UIAxes_9, 'Frequency(Hz)')
            app.PlotButton_9 = uibutton(app.Tab9, 'push');
            app.PlotButton_9.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_9Pushed, true);
            app.PlotButton_9.Text = 'Plot';
            app.Tab10 = uitab(app.TabGroup);
            app.Tab10.Title = 'Tab10';
            app.UIAxes_10 = uiaxes(app.Tab10);
            title(app.UIAxes_10, 'Spectrum of the signal')
            xlabel(app.UIAxes_10, 'Frequency (kHz)')
            ylabel(app.UIAxes_10, 'Amplitude')
            app.PlotButton_10 = uibutton(app.Tab10, 'push');
            app.PlotButton_10.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_10Pushed, true);
            app.PlotButton_10.Text = 'Plot';
            app.Panel = uipanel(app.UIFigure);
            app.MaleLabel = uilabel(app.Panel);
            app.MaleLabel.Text = 'Male          ';
            app.MaleLamp = uilamp(app.Panel);
            app.FemaleLabel = uilabel(app.Panel);
            app.FemaleLabel.Text = 'Female      ';
            app.FemaleLamp = uilamp(app.Panel);
            app.PitchEditFieldLabel = uilabel(app.Panel);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.Panel, 'numeric');
            app.GenderDetectionButton = uibutton(app.Panel, 'push');
            app.GenderDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @GenderDetectionButtonPushed, true);
            app.GenderDetectionButton.Text = 'Gender Detection';
            app.NotavoiceLampLabel = uilabel(app.Panel);
            app.NotavoiceLampLabel.Text = 'Not a voice';
            app.NotavoiceLamp = uilamp(app.Panel);
            app.HNRButton = uibutton(app.Panel, 'push');
            app.HNRButton.ButtonPushedFcn = createCallbackFcn(app, @HNRButtonPushed, true);
            app.HNRButton.Text = 'HNR';
            app.EditField_2 = uieditfield(app.Panel, 'numeric');
            app.ResetButton = uibutton(app.Panel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.RecordTab = uitab(app.TabGroup2);
            app.RecordTab.Title = 'Record';
            app.StartRecordingButton = uibutton(app.RecordTab, 'push');
            app.StartRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StartRecordingButtonPushed, true);
            app.StartRecordingButton.Text = 'Start Recording';
            app.TimesGaugeLabel = uilabel(app.RecordTab);
            app.TimesGaugeLabel.Text = 'Time(s)';
            app.TimesGauge = uigauge(app.RecordTab, 'semicircular');
            app.TimesGauge.Limits = [0 5];
            app.EditField = uieditfield(app.RecordTab, 'numeric');
            app.SaveButton = uibutton(app.RecordTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.UploadTab = uitab(app.TabGroup2);
            app.UploadTab.Title = 'Upload';
            app.UploadButton = uibutton(app.UploadTab, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.FileNameEditFieldLabel = uilabel(app.UploadTab);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.UploadTab, 'text');
            app.UploadedLampLabel = uilabel(app.UploadTab);
            app.UploadedLampLabel.Text = 'Uploaded';
            app.UploadedLamp = uilamp(app.UploadTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appFinal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end